classdef BAM_Online_Loading < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OnlineSavingUIFigure          matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        EnableUnit                    matlab.ui.control.Label
        ListBox3                      matlab.ui.control.ListBox
        ListBox1                      matlab.ui.control.ListBox
        ListBox2                      matlab.ui.control.ListBox
        F1                            matlab.ui.container.Panel
        F2                            matlab.ui.container.Panel
        F3                            matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        ElectrodePanel                matlab.ui.container.Panel
        E16CheckBox                   matlab.ui.control.CheckBox
        E15CheckBox                   matlab.ui.control.CheckBox
        E14CheckBox                   matlab.ui.control.CheckBox
        E13CheckBox                   matlab.ui.control.CheckBox
        E12CheckBox                   matlab.ui.control.CheckBox
        E11CheckBox                   matlab.ui.control.CheckBox
        E10CheckBox                   matlab.ui.control.CheckBox
        E9CheckBox                    matlab.ui.control.CheckBox
        E8CheckBox                    matlab.ui.control.CheckBox
        E7CheckBox                    matlab.ui.control.CheckBox
        E6CheckBox                    matlab.ui.control.CheckBox
        E5CheckBox                    matlab.ui.control.CheckBox
        E4CheckBox                    matlab.ui.control.CheckBox
        E3CheckBox                    matlab.ui.control.CheckBox
        E2CheckBox                    matlab.ui.control.CheckBox
        E1CheckBox                    matlab.ui.control.CheckBox
        DataCapture                   matlab.ui.control.Table
        BAMOnlineAnalysisSystemPanel  matlab.ui.container.Panel
        ExampleImage                  matlab.ui.control.Image
        delay_measurement             matlab.ui.control.Label
        WhichDataset                  matlab.ui.control.Label
        DatasetLabel                  matlab.ui.control.Label
        SavingLamp                    matlab.ui.control.Lamp
        SavingLampLabel               matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        FunctionButtonGroup           matlab.ui.container.ButtonGroup
        ValidTrialLabel               matlab.ui.control.Label
        TimeSpinner                   matlab.ui.control.Spinner
        TimeSpinnerLabel              matlab.ui.control.Label
        startmsEditField_2Label       matlab.ui.control.Label
        BarEnd                        matlab.ui.control.EditField
        startmsEditFieldLabel         matlab.ui.control.Label
        BarStart                      matlab.ui.control.EditField
        UserDefinedButton             matlab.ui.control.ToggleButton
        PSTHButton                    matlab.ui.control.ToggleButton
        BarButton                     matlab.ui.control.ToggleButton
        CategoryGrp                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        SpikeClearFlag % Description
        LfpClearFlag
        fid
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global BAM_config BAM_data
            root_dir = fN_localize_app;
            addpath(genpath(pwd))
            [BAM_config, BAM_data, app] = fN_initialize(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OnlineSavingUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {900, 900};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {1373, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function StartOnlineSavingButtonPushed(app, event)
            global BAM_config BAM_data


        end

        % Callback function
        function StopOnlineSavingButtonPushed(app, event)
            global BAM_config BAM_data
            BAM_config.is_saving = 0;
            app = fN_change_gui_color(app,BAM_config,BAM_data);
        end

        % Callback function
        function ElectrodeChanged(app, event)
             global BAM_config BAM_data
             StopOnlineSavingButtonPushed(app, event)
             [BAM_config,BAM_data, app] = fN_refresh_electrode(BAM_config, BAM_data, app);
             StartOnlineSavingButtonPushed(app, event)
        end

        % Button pushed function: RunButton
        function NewSession(app, event)
            global BAM_config BAM_data
            
            
            [~, time44] = AO_GetLatestTimeStamp;
            BAM_config.StartingTime = int32(time44/44);
            BAM_config.is_saving = 0;
            app = fN_change_gui_color(app,BAM_config,BAM_data);
            
            % save current result
            pause(0.1)
            [BAM_config,BAM_data, app] = fN_refresh_electrode(BAM_config, BAM_data, app);
            BAM_config.is_saving = 1;
            app = fN_change_gui_color(app,BAM_config,BAM_data);
            pause(0.1)

            % setting channel items
            elist = cell(0);
            for uu = (find(BAM_config.ElectrodeUsing))
                elist{end+1} = ['E' num2str(uu)];
            end
            app.ListBox3.Items = elist;
            app.ListBox2.Items = elist;
            app.ListBox1.Items = elist;
            app.F3.AutoResizeChildren='off';
            app.F2.AutoResizeChildren='off';
            app.F1.AutoResizeChildren='off';
            
            [BAM_config,BAM_data, app] = fN_saving(BAM_config, BAM_data, app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OnlineSavingUIFigure = uifigure('Visible', 'off');
            app.OnlineSavingUIFigure.Name = 'Online Saving';
            app.OnlineSavingUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.OnlineSavingUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.F3 = uipanel(app.LeftPanel);
            app.F3.Title = 'Plotting';
            app.F2 = uipanel(app.LeftPanel);
            app.F2.Title = 'Plotting';
            app.F1 = uipanel(app.LeftPanel);
            app.F1.Title = 'Plotting';
            app.ListBox2 = uilistbox(app.LeftPanel);
            app.ListBox1 = uilistbox(app.LeftPanel);
            app.ListBox3 = uilistbox(app.LeftPanel);
            app.EnableUnit = uilabel(app.LeftPanel);
            app.EnableUnit.Text = 'Enable and Disable Unit';
            app.RightPanel = uipanel(app.GridLayout);
            app.CategoryGrp = uiaxes(app.RightPanel);
            ylabel(app.CategoryGrp, 'Freq')
            zlabel(app.CategoryGrp, 'Z')
            app.CategoryGrp.XTick = [];
            app.FunctionButtonGroup = uibuttongroup(app.RightPanel);
            app.FunctionButtonGroup.Title = 'Function';
            app.BarButton = uitogglebutton(app.FunctionButtonGroup);
            app.BarButton.Text = 'Bar';
            app.BarButton.Value = true;
            app.PSTHButton = uitogglebutton(app.FunctionButtonGroup);
            app.PSTHButton.Text = 'PSTH';
            app.UserDefinedButton = uitogglebutton(app.FunctionButtonGroup);
            app.UserDefinedButton.Enable = 'off';
            app.UserDefinedButton.Text = 'UserDefined';
            app.BarStart = uieditfield(app.FunctionButtonGroup, 'text');
            app.BarStart.Value = '70';
            app.startmsEditFieldLabel = uilabel(app.FunctionButtonGroup);
            app.startmsEditFieldLabel.Text = 'start(ms)';
            app.BarEnd = uieditfield(app.FunctionButtonGroup, 'text');
            app.BarEnd.Value = '200';
            app.startmsEditField_2Label = uilabel(app.FunctionButtonGroup);
            app.startmsEditField_2Label.Text = 'start(ms)';
            app.TimeSpinnerLabel = uilabel(app.FunctionButtonGroup);
            app.TimeSpinnerLabel.Text = 'Time';
            app.TimeSpinner = uispinner(app.FunctionButtonGroup);
            app.TimeSpinner.Step = 2;
            app.TimeSpinner.Value = 80;
            app.ValidTrialLabel = uilabel(app.FunctionButtonGroup);
            app.ValidTrialLabel.Text = 'ValidTrial';
            app.BAMOnlineAnalysisSystemPanel = uipanel(app.RightPanel);
            app.BAMOnlineAnalysisSystemPanel.Title = 'BAM Online Analysis System';
            app.RunButton = uibutton(app.BAMOnlineAnalysisSystemPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @NewSession, true);
            app.RunButton.Icon = 'Blab_logo.png';
            app.RunButton.Text = '';
            app.SavingLampLabel = uilabel(app.BAMOnlineAnalysisSystemPanel);
            app.SavingLampLabel.Text = 'Saving';
            app.SavingLamp = uilamp(app.BAMOnlineAnalysisSystemPanel);
            app.DatasetLabel = uilabel(app.BAMOnlineAnalysisSystemPanel);
            app.DatasetLabel.Text = 'Dataset';
            app.WhichDataset = uilabel(app.BAMOnlineAnalysisSystemPanel);
            app.WhichDataset.Text = 'NULL';
            app.delay_measurement = uilabel(app.BAMOnlineAnalysisSystemPanel);
            app.delay_measurement.Text = 'Delay Info';
            app.ExampleImage = uiimage(app.BAMOnlineAnalysisSystemPanel);
            app.ExampleImage.ImageSource = 'Noise.png';
            app.DataCapture = uitable(app.RightPanel);
            app.DataCapture.ColumnName = {'Ch'; 'DataC'; 'Valid'; 'Mem'};
            app.DataCapture.RowName = {};
            app.DataCapture.Multiselect = 'off';
            app.ElectrodePanel = uipanel(app.RightPanel);
            app.ElectrodePanel.Title = 'Electrode';
            app.E1CheckBox = uicheckbox(app.ElectrodePanel);
            app.E1CheckBox.Text = 'E1';
            app.E1CheckBox.Value = true;
            app.E2CheckBox = uicheckbox(app.ElectrodePanel);
            app.E2CheckBox.Text = 'E2';
            app.E2CheckBox.Value = true;
            app.E3CheckBox = uicheckbox(app.ElectrodePanel);
            app.E3CheckBox.Text = 'E3';
            app.E4CheckBox = uicheckbox(app.ElectrodePanel);
            app.E4CheckBox.Text = 'E4';
            app.E5CheckBox = uicheckbox(app.ElectrodePanel);
            app.E5CheckBox.Text = 'E5';
            app.E6CheckBox = uicheckbox(app.ElectrodePanel);
            app.E6CheckBox.Text = 'E6';
            app.E7CheckBox = uicheckbox(app.ElectrodePanel);
            app.E7CheckBox.Text = 'E7';
            app.E8CheckBox = uicheckbox(app.ElectrodePanel);
            app.E8CheckBox.Text = 'E8';
            app.E9CheckBox = uicheckbox(app.ElectrodePanel);
            app.E9CheckBox.Text = 'E9';
            app.E10CheckBox = uicheckbox(app.ElectrodePanel);
            app.E10CheckBox.Text = 'E10';
            app.E11CheckBox = uicheckbox(app.ElectrodePanel);
            app.E11CheckBox.Text = 'E11';
            app.E12CheckBox = uicheckbox(app.ElectrodePanel);
            app.E12CheckBox.Text = 'E12';
            app.E13CheckBox = uicheckbox(app.ElectrodePanel);
            app.E13CheckBox.Text = 'E13';
            app.E14CheckBox = uicheckbox(app.ElectrodePanel);
            app.E14CheckBox.Text = 'E14';
            app.E15CheckBox = uicheckbox(app.ElectrodePanel);
            app.E15CheckBox.Text = 'E15';
            app.E16CheckBox = uicheckbox(app.ElectrodePanel);
            app.E16CheckBox.Text = 'E16';
            % Show the figure after all components are created
            app.OnlineSavingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BAM_Online_Loading

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OnlineSavingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OnlineSavingUIFigure)
        end
    end
end